classdef task2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        BrowseButton  matlab.ui.control.Button
        Panel         matlab.ui.container.Panel
        CoronalAxes   matlab.ui.control.UIAxes
        SagittalAxes  matlab.ui.control.UIAxes
        AxialAxes     matlab.ui.control.UIAxes
        ObliqueAxes   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        volume % 3D volume of images
        sagittalVol % sagittal plane slices
        coronalVol % coronal plane slices
        axialVol % axial plane slices
        imAxial % current axial slice on display
        imSagittal % current sagittal slice on display
        imCoronal % current coronal slice on display
    end
    
    methods (Access = private)
        
        function getAxial(app, depth)
            z = depth;
            % Show on UI
            app.imAxial = app.axialVol(:,:,ceil(depth/2));
            axis(app.AxialAxes, 'tight');
            imshow(app.imAxial,[],'parent',app.AxialAxes);
        end
        
        function getSagittal(app,depth)
            y = depth;
            for i= 1:y
                % Choose i-th slice in y-axis to be the singleton dimension to get x-z Plane
                % x-z plane now represents the i-th slice in the sagittal volume
                app.sagittalVol(:,:,i) = squeeze(app.volume(:,i,:)); 
            end
            
            % Show on UI
            app.imSagittal = app.sagittalVol(:,:,(depth/2));
            axis(app.SagittalAxes, 'tight');
            imshow(app.imSagittal,[],'parent',app.SagittalAxes);
        end
        
        function getCoronal(app, depth)
            x = depth;
            for i= 1:x 
                % Choose i-th slice in x-axis to be the singleton dimension to get y-z Plane
                % y-z plane now represents the i-th slice in the coronal volume
                app.coronalVol(:,:,i) = squeeze(app.volume(i,:,:));
            end
            
            % Show on UI
            app.imCoronal = app.coronalVol(:,:,(depth/2));
            axis(app.CoronalAxes, 'tight');
            imshow(app.imCoronal,[],'parent',app.CoronalAxes);
        end
             
        function clearAxes(app)
            cla(app.AxialAxes);
            cla(app.SagittalAxes);
            cla(app.CoronalAxes);
            cla(app.SagittalAxes);
        end
        
        function drawCrossHairs(app)
            % Axial Crosshair handling
            AxialCrossHair = drawcrosshair('Parent',app.AxialAxes,'Position',[50 50],'LineWidth',1,'Color','R');
            addlistener(AxialCrossHair,'MovingROI',@(src,data)UpdateROI(app,src,data,app.AxialAxes,app.imAxial));
%             
            % Sagittal Crosshair handling
            SagittalCrossHair = drawcrosshair('Parent',app.SagittalAxes,'Position',[50 50],'LineWidth',1,'Color','R');
            addlistener(SagittalCrossHair,'MovingROI',@(src,data)UpdateROI(app,src,data,app.SagittalAxes,app.imSagittal));

            % Coronal Crosshair handling
            CoronalCrossHair = drawcrosshair('Parent',app.CoronalAxes,'Position',[50 50],'LineWidth',1,'Color','R');
            addlistener(CoronalCrossHair,'MovingROI',@(src,data)UpdateROI(app,src,data,app.CoronalAxes,app.imCoronal));
            
        end
        
        function UpdateROI(app, src,data,axis,img)
            axisName = axis.Title.String;
            pos = ceil(data.CurrentPosition);
            disp(pos);
            
            switch axisName
                case 'Axial'
                    disp('Axial')
                case 'Sagittal'
                    disp('Sagittal')
                case 'Coronal'
                    disp('Coronal')
                otherwise
                    disp('None')
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            path = uigetdir('Open Dicom Directory');
            figure(app.UIFigure);
            
            try 
                % Clear all axes
                clearAxes(app);
                
                % Create a 4-D volume, V, from a set of DICOM files specified by source. 
                % The dicomreadVolume function identifies the correct order of the images and creates a 4-D volume.
                [V,spatial,dim] = dicomreadVolume(path);
                
                % Use the squeeze function to remove any singleton dimensions.
                app.volume = squeeze(V);
                app.axialVol = app.volume;
                
                % Get the volume dimesnions.
                [x,y,z] = size(app.volume);
                
                % Construct empty volumes for the 2 other planes.
                app.sagittalVol = zeros(x ,z, y);  % x-z Plane
                app.coronalVol = zeros( y, z, x);  % y-z Plane
                
                % Get Sagittal Plane
                getAxial(app,z);
                
                % Get Sagittal Plane
                getSagittal(app,y);
                
                % Get Sagittal Plane
                getCoronal(app,x);
                
                %CrossHairs
                drawCrossHairs(app)
                
            catch
                errordlg('Path is a non-dicom directory','Error')
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Panel = uipanel(app.UIFigure);
            app.CoronalAxes = uiaxes(app.Panel);
            title(app.CoronalAxes, 'Coronal')
            xlabel(app.CoronalAxes, '')
            ylabel(app.CoronalAxes, '')
            app.CoronalAxes.XTickLabel = '';
            app.CoronalAxes.YTickLabel = '';
            app.SagittalAxes = uiaxes(app.Panel);
            title(app.SagittalAxes, 'Sagittal')
            xlabel(app.SagittalAxes, '')
            ylabel(app.SagittalAxes, '')
            app.SagittalAxes.XTickLabel = '';
            app.SagittalAxes.YTickLabel = '';
            app.AxialAxes = uiaxes(app.Panel);
            title(app.AxialAxes, 'Axial')
            xlabel(app.AxialAxes, '')
            ylabel(app.AxialAxes, '')
            app.AxialAxes.XTickLabel = '';
            app.AxialAxes.YTickLabel = '';
            app.ObliqueAxes = uiaxes(app.Panel);
            title(app.ObliqueAxes, 'Oblique')
            xlabel(app.ObliqueAxes, '')
            ylabel(app.ObliqueAxes, '')
            app.ObliqueAxes.XTickLabel = '';
            app.ObliqueAxes.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end